classdef buildIXNStack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BuildStacksUIFigure           matlab.ui.Figure
        ConfirmButton                 matlab.ui.control.Button
        A01                           matlab.ui.control.CheckBox
        timepointsLabel               matlab.ui.control.Label
        TimePoints                    matlab.ui.control.NumericEditField
        B01                           matlab.ui.control.CheckBox
        C01                           matlab.ui.control.CheckBox
        D01                           matlab.ui.control.CheckBox
        RowALabel                     matlab.ui.control.Label
        RowBLabel                     matlab.ui.control.Label
        RowCLabel                     matlab.ui.control.Label
        RowDLabel                     matlab.ui.control.Label
        ColumnLabel                   matlab.ui.control.Label
        A02                           matlab.ui.control.CheckBox
        B02                           matlab.ui.control.CheckBox
        C02                           matlab.ui.control.CheckBox
        D02                           matlab.ui.control.CheckBox
        A03                           matlab.ui.control.CheckBox
        B03                           matlab.ui.control.CheckBox
        C03                           matlab.ui.control.CheckBox
        D03                           matlab.ui.control.CheckBox
        A04                           matlab.ui.control.CheckBox
        B04                           matlab.ui.control.CheckBox
        C04                           matlab.ui.control.CheckBox
        D04                           matlab.ui.control.CheckBox
        A05                           matlab.ui.control.CheckBox
        B05                           matlab.ui.control.CheckBox
        C05                           matlab.ui.control.CheckBox
        D05                           matlab.ui.control.CheckBox
        A06                           matlab.ui.control.CheckBox
        B06                           matlab.ui.control.CheckBox
        C06                           matlab.ui.control.CheckBox
        D06                           matlab.ui.control.CheckBox
        Channel1EditFieldLabel        matlab.ui.control.Label
        Channel1                      matlab.ui.control.EditField
        Channel2EditFieldLabel        matlab.ui.control.Label
        Channel2                      matlab.ui.control.EditField
        Channel3EditFieldLabel        matlab.ui.control.Label
        Channel3                      matlab.ui.control.EditField
        Channel4EditFieldLabel        matlab.ui.control.Label
        Channel4                      matlab.ui.control.EditField
        DateprefixEditFieldLabel      matlab.ui.control.Label
        DatePrefix                    matlab.ui.control.EditField
        NoWField                      matlab.ui.control.CheckBox
        RemovethefirstpointsLabel     matlab.ui.control.Label
        N                             matlab.ui.control.NumericEditField
        ButtonGroup                   matlab.ui.container.ButtonGroup
        LZW                           matlab.ui.control.RadioButton
        DEFLATE                       matlab.ui.control.RadioButton
        None                          matlab.ui.control.RadioButton
        SizeReduction                 matlab.ui.control.Label
        WhichpositionsEditFieldLabel  matlab.ui.control.Label
        Positions                     matlab.ui.control.EditField
        CheckChannel1                 matlab.ui.control.CheckBox
        CheckChannel2                 matlab.ui.control.CheckBox
        CheckChannel3                 matlab.ui.control.CheckBox
        CheckChannel4                 matlab.ui.control.CheckBox
        RowALabel_2                   matlab.ui.control.Label
        RowBLabel_2                   matlab.ui.control.Label
        RowCLabel_2                   matlab.ui.control.Label
        RowDLabel_2                   matlab.ui.control.Label
        E01                           matlab.ui.control.CheckBox
        F01                           matlab.ui.control.CheckBox
        G01                           matlab.ui.control.CheckBox
        H01                           matlab.ui.control.CheckBox
        E02                           matlab.ui.control.CheckBox
        F02                           matlab.ui.control.CheckBox
        G02                           matlab.ui.control.CheckBox
        H02                           matlab.ui.control.CheckBox
        E03                           matlab.ui.control.CheckBox
        F03                           matlab.ui.control.CheckBox
        G03                           matlab.ui.control.CheckBox
        H03                           matlab.ui.control.CheckBox
        E04                           matlab.ui.control.CheckBox
        F04                           matlab.ui.control.CheckBox
        G04                           matlab.ui.control.CheckBox
        H04                           matlab.ui.control.CheckBox
        E05                           matlab.ui.control.CheckBox
        F05                           matlab.ui.control.CheckBox
        G05                           matlab.ui.control.CheckBox
        H05                           matlab.ui.control.CheckBox
        E06                           matlab.ui.control.CheckBox
        F06                           matlab.ui.control.CheckBox
        G06                           matlab.ui.control.CheckBox
        H06                           matlab.ui.control.CheckBox
        A07                           matlab.ui.control.CheckBox
        B07                           matlab.ui.control.CheckBox
        C07                           matlab.ui.control.CheckBox
        D07                           matlab.ui.control.CheckBox
        A08                           matlab.ui.control.CheckBox
        B08                           matlab.ui.control.CheckBox
        C08                           matlab.ui.control.CheckBox
        D08                           matlab.ui.control.CheckBox
        A09                           matlab.ui.control.CheckBox
        B09                           matlab.ui.control.CheckBox
        C09                           matlab.ui.control.CheckBox
        D09                           matlab.ui.control.CheckBox
        A10                           matlab.ui.control.CheckBox
        B10                           matlab.ui.control.CheckBox
        C10                           matlab.ui.control.CheckBox
        D10                           matlab.ui.control.CheckBox
        A11                           matlab.ui.control.CheckBox
        B11                           matlab.ui.control.CheckBox
        C11                           matlab.ui.control.CheckBox
        D11                           matlab.ui.control.CheckBox
        A12                           matlab.ui.control.CheckBox
        B12                           matlab.ui.control.CheckBox
        C12                           matlab.ui.control.CheckBox
        D12                           matlab.ui.control.CheckBox
        E07                           matlab.ui.control.CheckBox
        F07                           matlab.ui.control.CheckBox
        G07                           matlab.ui.control.CheckBox
        H07                           matlab.ui.control.CheckBox
        E08                           matlab.ui.control.CheckBox
        F08                           matlab.ui.control.CheckBox
        G08                           matlab.ui.control.CheckBox
        H08                           matlab.ui.control.CheckBox
        E09                           matlab.ui.control.CheckBox
        F09                           matlab.ui.control.CheckBox
        G09                           matlab.ui.control.CheckBox
        H09                           matlab.ui.control.CheckBox
        E10                           matlab.ui.control.CheckBox
        F10                           matlab.ui.control.CheckBox
        G10                           matlab.ui.control.CheckBox
        H10                           matlab.ui.control.CheckBox
        E11                           matlab.ui.control.CheckBox
        F11                           matlab.ui.control.CheckBox
        G11                           matlab.ui.control.CheckBox
        H11                           matlab.ui.control.CheckBox
        E12                           matlab.ui.control.CheckBox
        F12                           matlab.ui.control.CheckBox
        G12                           matlab.ui.control.CheckBox
        H12                           matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MoveToCenter(app)
            movegui(app.BuildStacksUIFigure, 'center');
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            %% Get parameters
            AllWells = {};
            AllWellsNoZero = {};
            if app.A01.Value
                AllWells = [AllWells(:)', {'A01'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'A1'}];
            end
            if app.B01.Value
                AllWells = [AllWells(:)', {'B01'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'B1'}];
            end
            if app.C01.Value
                AllWells = [AllWells(:)', {'C01'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'C1'}];
            end
            if app.D01.Value
                AllWells = [AllWells(:)', {'D01'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'D1'}];
            end
            if app.A02.Value
                AllWells = [AllWells(:)', {'A02'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'A2'}];
            end
            if app.B02.Value
                AllWells = [AllWells(:)', {'B02'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'B2'}];
            end
            if app.C02.Value
                AllWells = [AllWells(:)', {'C02'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'C2'}];
            end
            if app.D02.Value
                AllWells = [AllWells(:)', {'D02'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'D2'}];
            end
            if app.A03.Value
                AllWells = [AllWells(:)', {'A03'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'A3'}];
            end
            if app.B03.Value
                AllWells = [AllWells(:)', {'B03'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'B3'}];
            end
            if app.C03.Value
                AllWells = [AllWells(:)', {'C03'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'C3'}];
            end
            if app.D03.Value
                AllWells = [AllWells(:)', {'D03'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'D3'}];
            end
            if app.A04.Value
                AllWells = [AllWells(:)', {'A04'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'A4'}];
            end
            if app.B04.Value
                AllWells = [AllWells(:)', {'B04'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'B4'}];
            end
            if app.C04.Value
                AllWells = [AllWells(:)', {'C04'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'C4'}];
            end
            if app.D04.Value
                AllWells = [AllWells(:)', {'D04'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'D4'}];
            end
            if app.A05.Value
                AllWells = [AllWells(:)', {'A05'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'A5'}];
            end
            if app.B05.Value
                AllWells = [AllWells(:)', {'B05'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'B5'}];
            end
            if app.C05.Value
                AllWells = [AllWells(:)', {'C05'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'C5'}];
            end
            if app.D05.Value
                AllWells = [AllWells(:)', {'D05'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'D5'}];
            end
            if app.A06.Value
                AllWells = [AllWells(:)', {'A06'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'A6'}];
            end
            if app.B06.Value
                AllWells = [AllWells(:)', {'B06'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'B6'}];
            end
            if app.C06.Value
                AllWells = [AllWells(:)', {'C06'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'C6'}];
            end
            if app.D06.Value
                AllWells = [AllWells(:)', {'D06'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'D6'}];
            end
            if app.E01.Value
                AllWells = [AllWells(:)', {'E01'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'E1'}];
            end
            if app.F01.Value
                AllWells = [AllWells(:)', {'F01'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'F1'}];
            end
            if app.G01.Value
                AllWells = [AllWells(:)', {'G01'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'G1'}];
            end
            if app.H01.Value
                AllWells = [AllWells(:)', {'H01'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'H1'}];
            end
            if app.E02.Value
                AllWells = [AllWells(:)', {'E02'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'E2'}];
            end
            if app.F02.Value
                AllWells = [AllWells(:)', {'F02'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'F2'}];
            end
            if app.G02.Value
                AllWells = [AllWells(:)', {'G02'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'G2'}];
            end
            if app.H02.Value
                AllWells = [AllWells(:)', {'H02'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'H2'}];
            end
            if app.E03.Value
                AllWells = [AllWells(:)', {'E03'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'E3'}];
            end
            if app.F03.Value
                AllWells = [AllWells(:)', {'F03'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'F3'}];
            end
            if app.G03.Value
                AllWells = [AllWells(:)', {'G03'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'G3'}];
            end
            if app.H03.Value
                AllWells = [AllWells(:)', {'H03'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'H3'}];
            end
            if app.E04.Value
                AllWells = [AllWells(:)', {'E04'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'E4'}];
            end
            if app.F04.Value
                AllWells = [AllWells(:)', {'F04'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'F4'}];
            end
            if app.G04.Value
                AllWells = [AllWells(:)', {'G04'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'G4'}];
            end
            if app.H04.Value
                AllWells = [AllWells(:)', {'H04'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'H4'}];
            end
            if app.E05.Value
                AllWells = [AllWells(:)', {'E05'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'E5'}];
            end
            if app.F05.Value
                AllWells = [AllWells(:)', {'F05'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'F5'}];
            end
            if app.G05.Value
                AllWells = [AllWells(:)', {'G05'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'G5'}];
            end
            if app.H05.Value
                AllWells = [AllWells(:)', {'H05'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'H5'}];
            end
            if app.E06.Value
                AllWells = [AllWells(:)', {'E06'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'E6'}];
            end
            if app.F06.Value
                AllWells = [AllWells(:)', {'F06'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'F6'}];
            end
            if app.G06.Value
                AllWells = [AllWells(:)', {'G06'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'G6'}];
            end
            if app.H06.Value
                AllWells = [AllWells(:)', {'H06'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'H6'}];
            end
            if app.A07.Value
                AllWells = [AllWells(:)', {'A07'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'A7'}];
            end
            if app.B07.Value
                AllWells = [AllWells(:)', {'B07'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'B7'}];
            end
            if app.C07.Value
                AllWells = [AllWells(:)', {'C07'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'C7'}];
            end
            if app.D07.Value
                AllWells = [AllWells(:)', {'D07'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'D7'}];
            end
            if app.A08.Value
                AllWells = [AllWells(:)', {'A08'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'A8'}];
            end
            if app.B08.Value
                AllWells = [AllWells(:)', {'B08'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'B8'}];
            end
            if app.C08.Value
                AllWells = [AllWells(:)', {'C08'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'C8'}];
            end
            if app.D08.Value
                AllWells = [AllWells(:)', {'D08'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'D8'}];
            end
            if app.A09.Value
                AllWells = [AllWells(:)', {'A09'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'A9'}];
            end
            if app.B09.Value
                AllWells = [AllWells(:)', {'B09'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'B9'}];
            end
            if app.C09.Value
                AllWells = [AllWells(:)', {'C09'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'C9'}];
            end
            if app.D09.Value
                AllWells = [AllWells(:)', {'D09'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'D9'}];
            end
            if app.A10.Value
                AllWells = [AllWells(:)', {'A10'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'A10'}];
            end
            if app.B10.Value
                AllWells = [AllWells(:)', {'B10'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'B10'}];
            end
            if app.C10.Value
                AllWells = [AllWells(:)', {'C10'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'C10'}];
            end
            if app.D10.Value
                AllWells = [AllWells(:)', {'D10'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'D10'}];
            end
            if app.A11.Value
                AllWells = [AllWells(:)', {'A11'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'A11'}];
            end
            if app.B11.Value
                AllWells = [AllWells(:)', {'B11'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'B11'}];
            end
            if app.C11.Value
                AllWells = [AllWells(:)', {'C11'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'C11'}];
            end
            if app.D11.Value
                AllWells = [AllWells(:)', {'D11'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'D11'}];
            end
            if app.A12.Value
                AllWells = [AllWells(:)', {'A12'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'A12'}];
            end
            if app.B12.Value
                AllWells = [AllWells(:)', {'B12'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'B12'}];
            end
            if app.C12.Value
                AllWells = [AllWells(:)', {'C12'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'C12'}];
            end
            if app.D12.Value
                AllWells = [AllWells(:)', {'D12'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'D12'}];
            end
            if app.E07.Value
                AllWells = [AllWells(:)', {'E07'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'E7'}];
            end
            if app.F07.Value
                AllWells = [AllWells(:)', {'F07'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'F7'}];
            end
            if app.G07.Value
                AllWells = [AllWells(:)', {'G07'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'G7'}];
            end
            if app.H07.Value
                AllWells = [AllWells(:)', {'H07'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'H7'}];
            end
            if app.E08.Value
                AllWells = [AllWells(:)', {'E08'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'E8'}];
            end
            if app.F08.Value
                AllWells = [AllWells(:)', {'F08'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'F8'}];
            end
            if app.G08.Value
                AllWells = [AllWells(:)', {'G08'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'G8'}];
            end
            if app.H08.Value
                AllWells = [AllWells(:)', {'H08'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'H8'}];
            end
            if app.E09.Value
                AllWells = [AllWells(:)', {'E09'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'E9'}];
            end
            if app.F09.Value
                AllWells = [AllWells(:)', {'F09'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'F9'}];
            end
            if app.G09.Value
                AllWells = [AllWells(:)', {'G09'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'G9'}];
            end
            if app.H09.Value
                AllWells = [AllWells(:)', {'H09'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'H9'}];
            end
            if app.E10.Value
                AllWells = [AllWells(:)', {'E10'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'E10'}];
            end
            if app.F10.Value
                AllWells = [AllWells(:)', {'F10'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'F10'}];
            end
            if app.G10.Value
                AllWells = [AllWells(:)', {'G10'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'G10'}];
            end
            if app.H10.Value
                AllWells = [AllWells(:)', {'H10'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'H10'}];
            end
            if app.E11.Value
                AllWells = [AllWells(:)', {'E11'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'E11'}];
            end
            if app.F11.Value
                AllWells = [AllWells(:)', {'F11'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'F11'}];
            end
            if app.G11.Value
                AllWells = [AllWells(:)', {'G11'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'G11'}];
            end
            if app.H11.Value
                AllWells = [AllWells(:)', {'H11'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'H11'}];
            end
            if app.E12.Value
                AllWells = [AllWells(:)', {'E12'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'E12'}];
            end
            if app.F12.Value
                AllWells = [AllWells(:)', {'F12'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'F12'}];
            end
            if app.G12.Value
                AllWells = [AllWells(:)', {'G12'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'G12'}];
            end
            if app.H12.Value
                AllWells = [AllWells(:)', {'H12'}];
                AllWellsNoZero = [AllWellsNoZero(:)', {'H12'}];
            end
            PositionList = parsePositionList(app.Positions.Value);
            TotalChannels = 1 : 4;
            ChannelList = TotalChannels([app.CheckChannel1.Value, app.CheckChannel2.Value, ...
                app.CheckChannel3.Value, app.CheckChannel4.Value]);
            TotalTimePoints = floor(app.TimePoints.Value);
            if isempty(app.DatePrefix.Value)
                uialert(app.BuildStacksUIFigure, ...
                    'No date prefix is supplied.', 'Input Error');
                return;
            else
                ExperimentDatePrefix = app.DatePrefix.Value;
            end
            removeSpace = @(String) String(~isspace(String));
            ChannelNameList = {removeSpace(app.Channel1.Value), ...
                removeSpace(app.Channel2.Value), ...
                removeSpace(app.Channel3.Value), ...
                removeSpace(app.Channel4.Value)};
            NoW = app.NoWField.Value; % logical
            RemoveFirstNTimePoints = round(app.N.Value);
            if app.LZW.Value
                CompressionAlgorithm = 'lzw';
            end
            if app.DEFLATE.Value
                CompressionAlgorithm = 'deflate';
            end
            if app.None.Value
                CompressionAlgorithm = 'none';
            end
            %% Close the GUI
            delete(app.BuildStacksUIFigure);
            
            %% Process image files
            % Delete all thumbnail files
            for l = (RemoveFirstNTimePoints + 1) : TotalTimePoints
                delete(sprintf('.%cTimePoint_%d%c*thumb*', filesep, l, filesep));
            end
            
            % Build stack(s)
            for i = 1 : length(AllWells)
                for j = PositionList
                    if NoW
                        
                        if strcmpi(ChannelNameList{1}, 'trans')
                            StackPath = sprintf('%s_%s_%d.tif', ...
                                ExperimentDatePrefix, AllWellsNoZero{i}, j);
                        else
                            StackPath = sprintf('%s_%s_uncalibrated_%s_%d.tif', ...
                                ExperimentDatePrefix, lower(ChannelNameList{1}), ...
                                AllWellsNoZero{i}, j);
                        end
                        % Build the stack
                        for l = (RemoveFirstNTimePoints + 1) : TotalTimePoints
                            if ispc
                                % For Windows
                                try
                                    IndividualImageFilename = ls(sprintf(...
                                        '.%cTimePoint_%d%c*_%s_s%d*', ...
                                        filesep, l, filesep, AllWells{i}, j));
                                    imwrite(imread(sprintf('.%cTimePoint_%d%c%s', ...
                                        filesep, l, filesep, deblank(IndividualImageFilename(1, :)))), ...
                                        StackPath, 'Compression', CompressionAlgorithm, ...
                                        'writemode', 'append');
                                catch Error
                                    cprintf('Errors', '! ');
                                    fprintf('(%s_s%d) %s\n', AllWells{i}, j, Error.message);
                                end
                            else
                                % For Unix(-like) OS
                                try
                                    IndividualImagePath = strsplit(ls(sprintf(...
                                        '.%cTimePoint_%d%c*_%s_s%d*', ...
                                        filesep, l, filesep, AllWells{i}, j)), {'\n', '\t'});
                                    imwrite(imread(IndividualImagePath{1}), StackPath, ...
                                        'Compression', CompressionAlgorithm, ...
                                        'writemode', 'append');
                                catch Error
                                    cprintf('Errors', ['! ', Error.message, '\n']);
                                end
                            end
                        end
                        
                    else
                        
                        for k = ChannelList
                            % Determine the name of the stack
                            if strcmpi(ChannelNameList{k}, 'trans')
                                StackPath = sprintf('%s_%s_%d.tif', ...
                                    ExperimentDatePrefix, AllWellsNoZero{i}, j);
                            else
                                StackPath = sprintf('%s_%s_uncalibrated_%s_%d.tif', ...
                                    ExperimentDatePrefix, lower(ChannelNameList{k}), ...
                                    AllWellsNoZero{i}, j);
                            end
                            % Build the stack
                            for l = (RemoveFirstNTimePoints + 1) : TotalTimePoints
                                if ispc
                                    % For Windows
                                    try
                                        IndividualImageFilename = ls(sprintf(...
                                            '.%cTimePoint_%d%c*_%s_s%d_w%d*', ...
                                            filesep, l, filesep, AllWells{i}, j, k));
                                        imwrite(imread(sprintf('.%cTimePoint_%d%c%s', ...
                                            filesep, l, filesep, deblank(IndividualImageFilename(1, :)))), ...
                                            StackPath, 'Compression', CompressionAlgorithm, ...
                                            'writemode', 'append');
                                    catch Error
                                        cprintf('Errors', '! ');
                                        fprintf('(%s_s%d_w%d) %s\n', AllWells{i}, j, k, Error.message);
                                    end
                                else
                                    % For Unix(-like) OS
                                    try
                                        IndividualImagePath = strsplit(ls(sprintf(...
                                            '.%cTimePoint_%d%c*_%s_s%d_w%d*', ...
                                            filesep, l, filesep, AllWells{i}, j, k)), {'\n', '\t'});
                                        imwrite(imread(IndividualImagePath{1}), StackPath, ...
                                            'Compression', CompressionAlgorithm, ...
                                            'writemode', 'append');
                                    catch Error
                                        cprintf('Errors', ['! ', Error.message, '\n']);
                                    end
                                end
                            end
                        end
                        
                    end
                end
            end
            fprintf('buildIXNStack: done!\n');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BuildStacksUIFigure = uifigure('Visible', 'off');
            app.BuildStacksUIFigure.Name = 'Build Stack(s)';
            app.ConfirmButton = uibutton(app.BuildStacksUIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.A01 = uicheckbox(app.BuildStacksUIFigure);
            app.A01.Text = '';
            app.timepointsLabel = uilabel(app.BuildStacksUIFigure);
            app.timepointsLabel.Text = 'time point(s)';
            app.TimePoints = uieditfield(app.BuildStacksUIFigure, 'numeric');
            app.TimePoints.Value = 145;
            app.B01 = uicheckbox(app.BuildStacksUIFigure);
            app.B01.Text = '';
            app.C01 = uicheckbox(app.BuildStacksUIFigure);
            app.C01.Text = '';
            app.D01 = uicheckbox(app.BuildStacksUIFigure);
            app.D01.Text = '';
            app.RowALabel = uilabel(app.BuildStacksUIFigure);
            app.RowALabel.Text = 'A';
            app.RowBLabel = uilabel(app.BuildStacksUIFigure);
            app.RowBLabel.Text = 'B';
            app.RowCLabel = uilabel(app.BuildStacksUIFigure);
            app.RowCLabel.Text = 'C';
            app.RowDLabel = uilabel(app.BuildStacksUIFigure);
            app.RowDLabel.Text = 'D';
            app.ColumnLabel = uilabel(app.BuildStacksUIFigure);
            app.ColumnLabel.Text = '01 02 03 04 05 06 07 08 09 10 11 12';
            app.A02 = uicheckbox(app.BuildStacksUIFigure);
            app.A02.Text = '';
            app.B02 = uicheckbox(app.BuildStacksUIFigure);
            app.B02.Text = '';
            app.C02 = uicheckbox(app.BuildStacksUIFigure);
            app.C02.Text = '';
            app.D02 = uicheckbox(app.BuildStacksUIFigure);
            app.D02.Text = '';
            app.A03 = uicheckbox(app.BuildStacksUIFigure);
            app.A03.Text = '';
            app.B03 = uicheckbox(app.BuildStacksUIFigure);
            app.B03.Text = '';
            app.C03 = uicheckbox(app.BuildStacksUIFigure);
            app.C03.Text = '';
            app.D03 = uicheckbox(app.BuildStacksUIFigure);
            app.D03.Text = '';
            app.A04 = uicheckbox(app.BuildStacksUIFigure);
            app.A04.Text = '';
            app.B04 = uicheckbox(app.BuildStacksUIFigure);
            app.B04.Text = '';
            app.C04 = uicheckbox(app.BuildStacksUIFigure);
            app.C04.Text = '';
            app.D04 = uicheckbox(app.BuildStacksUIFigure);
            app.D04.Text = '';
            app.A05 = uicheckbox(app.BuildStacksUIFigure);
            app.A05.Text = '';
            app.B05 = uicheckbox(app.BuildStacksUIFigure);
            app.B05.Text = '';
            app.C05 = uicheckbox(app.BuildStacksUIFigure);
            app.C05.Text = '';
            app.D05 = uicheckbox(app.BuildStacksUIFigure);
            app.D05.Text = '';
            app.A06 = uicheckbox(app.BuildStacksUIFigure);
            app.A06.Text = '';
            app.B06 = uicheckbox(app.BuildStacksUIFigure);
            app.B06.Text = '';
            app.C06 = uicheckbox(app.BuildStacksUIFigure);
            app.C06.Text = '';
            app.D06 = uicheckbox(app.BuildStacksUIFigure);
            app.D06.Text = '';
            app.Channel1EditFieldLabel = uilabel(app.BuildStacksUIFigure);
            app.Channel1EditFieldLabel.Text = 'Channel 1';
            app.Channel1 = uieditfield(app.BuildStacksUIFigure, 'text');
            app.Channel1.Value = 'trans';
            app.Channel2EditFieldLabel = uilabel(app.BuildStacksUIFigure);
            app.Channel2EditFieldLabel.Text = 'Channel 2';
            app.Channel2 = uieditfield(app.BuildStacksUIFigure, 'text');
            app.Channel2.Value = 'red';
            app.Channel3EditFieldLabel = uilabel(app.BuildStacksUIFigure);
            app.Channel3EditFieldLabel.Text = 'Channel 3';
            app.Channel3 = uieditfield(app.BuildStacksUIFigure, 'text');
            app.Channel3.Value = 'green';
            app.Channel4EditFieldLabel = uilabel(app.BuildStacksUIFigure);
            app.Channel4EditFieldLabel.Text = 'Channel 4';
            app.Channel4 = uieditfield(app.BuildStacksUIFigure, 'text');
            app.Channel4.Value = 'blue';
            app.DateprefixEditFieldLabel = uilabel(app.BuildStacksUIFigure);
            app.DateprefixEditFieldLabel.Text = 'Date prefix';
            app.DatePrefix = uieditfield(app.BuildStacksUIFigure, 'text');
            app.NoWField = uicheckbox(app.BuildStacksUIFigure);
            app.NoWField.Text = {'  Only one channel was imaged'; '     (no "_w" field in filenames)'};
            app.RemovethefirstpointsLabel = uilabel(app.BuildStacksUIFigure);
            app.RemovethefirstpointsLabel.Text = 'Remove the first               point(s)';
            app.N = uieditfield(app.BuildStacksUIFigure, 'numeric');
            app.ButtonGroup = uibuttongroup(app.BuildStacksUIFigure);
            app.LZW = uiradiobutton(app.ButtonGroup);
            app.LZW.Text = 'LZW';
            app.DEFLATE = uiradiobutton(app.ButtonGroup);
            app.DEFLATE.Text = 'DEFLATE';
            app.None = uiradiobutton(app.ButtonGroup);
            app.None.Text = '(None)';
            app.None.Value = true;
            app.SizeReduction = uilabel(app.ButtonGroup);
            app.SizeReduction.Text = 'Size reduction';
            app.WhichpositionsEditFieldLabel = uilabel(app.BuildStacksUIFigure);
            app.WhichpositionsEditFieldLabel.Text = 'Which position(s)? ';
            app.Positions = uieditfield(app.BuildStacksUIFigure, 'text');
            app.CheckChannel1 = uicheckbox(app.BuildStacksUIFigure);
            app.CheckChannel1.Text = '';
            app.CheckChannel2 = uicheckbox(app.BuildStacksUIFigure);
            app.CheckChannel2.Text = '';
            app.CheckChannel3 = uicheckbox(app.BuildStacksUIFigure);
            app.CheckChannel3.Text = '';
            app.CheckChannel4 = uicheckbox(app.BuildStacksUIFigure);
            app.CheckChannel4.Text = '';
            app.RowALabel_2 = uilabel(app.BuildStacksUIFigure);
            app.RowALabel_2.Text = 'E';
            app.RowBLabel_2 = uilabel(app.BuildStacksUIFigure);
            app.RowBLabel_2.Text = 'F';
            app.RowCLabel_2 = uilabel(app.BuildStacksUIFigure);
            app.RowCLabel_2.Text = 'G';
            app.RowDLabel_2 = uilabel(app.BuildStacksUIFigure);
            app.RowDLabel_2.Text = 'H';
            app.E01 = uicheckbox(app.BuildStacksUIFigure);
            app.E01.Text = '';
            app.F01 = uicheckbox(app.BuildStacksUIFigure);
            app.F01.Text = '';
            app.G01 = uicheckbox(app.BuildStacksUIFigure);
            app.G01.Text = '';
            app.H01 = uicheckbox(app.BuildStacksUIFigure);
            app.H01.Text = '';
            app.E02 = uicheckbox(app.BuildStacksUIFigure);
            app.E02.Text = '';
            app.F02 = uicheckbox(app.BuildStacksUIFigure);
            app.F02.Text = '';
            app.G02 = uicheckbox(app.BuildStacksUIFigure);
            app.G02.Text = '';
            app.H02 = uicheckbox(app.BuildStacksUIFigure);
            app.H02.Text = '';
            app.E03 = uicheckbox(app.BuildStacksUIFigure);
            app.E03.Text = '';
            app.F03 = uicheckbox(app.BuildStacksUIFigure);
            app.F03.Text = '';
            app.G03 = uicheckbox(app.BuildStacksUIFigure);
            app.G03.Text = '';
            app.H03 = uicheckbox(app.BuildStacksUIFigure);
            app.H03.Text = '';
            app.E04 = uicheckbox(app.BuildStacksUIFigure);
            app.E04.Text = '';
            app.F04 = uicheckbox(app.BuildStacksUIFigure);
            app.F04.Text = '';
            app.G04 = uicheckbox(app.BuildStacksUIFigure);
            app.G04.Text = '';
            app.H04 = uicheckbox(app.BuildStacksUIFigure);
            app.H04.Text = '';
            app.E05 = uicheckbox(app.BuildStacksUIFigure);
            app.E05.Text = '';
            app.F05 = uicheckbox(app.BuildStacksUIFigure);
            app.F05.Text = '';
            app.G05 = uicheckbox(app.BuildStacksUIFigure);
            app.G05.Text = '';
            app.H05 = uicheckbox(app.BuildStacksUIFigure);
            app.H05.Text = '';
            app.E06 = uicheckbox(app.BuildStacksUIFigure);
            app.E06.Text = '';
            app.F06 = uicheckbox(app.BuildStacksUIFigure);
            app.F06.Text = '';
            app.G06 = uicheckbox(app.BuildStacksUIFigure);
            app.G06.Text = '';
            app.H06 = uicheckbox(app.BuildStacksUIFigure);
            app.H06.Text = '';
            app.A07 = uicheckbox(app.BuildStacksUIFigure);
            app.A07.Text = '';
            app.B07 = uicheckbox(app.BuildStacksUIFigure);
            app.B07.Text = '';
            app.C07 = uicheckbox(app.BuildStacksUIFigure);
            app.C07.Text = '';
            app.D07 = uicheckbox(app.BuildStacksUIFigure);
            app.D07.Text = '';
            app.A08 = uicheckbox(app.BuildStacksUIFigure);
            app.A08.Text = '';
            app.B08 = uicheckbox(app.BuildStacksUIFigure);
            app.B08.Text = '';
            app.C08 = uicheckbox(app.BuildStacksUIFigure);
            app.C08.Text = '';
            app.D08 = uicheckbox(app.BuildStacksUIFigure);
            app.D08.Text = '';
            app.A09 = uicheckbox(app.BuildStacksUIFigure);
            app.A09.Text = '';
            app.B09 = uicheckbox(app.BuildStacksUIFigure);
            app.B09.Text = '';
            app.C09 = uicheckbox(app.BuildStacksUIFigure);
            app.C09.Text = '';
            app.D09 = uicheckbox(app.BuildStacksUIFigure);
            app.D09.Text = '';
            app.A10 = uicheckbox(app.BuildStacksUIFigure);
            app.A10.Text = '';
            app.B10 = uicheckbox(app.BuildStacksUIFigure);
            app.B10.Text = '';
            app.C10 = uicheckbox(app.BuildStacksUIFigure);
            app.C10.Text = '';
            app.D10 = uicheckbox(app.BuildStacksUIFigure);
            app.D10.Text = '';
            app.A11 = uicheckbox(app.BuildStacksUIFigure);
            app.A11.Text = '';
            app.B11 = uicheckbox(app.BuildStacksUIFigure);
            app.B11.Text = '';
            app.C11 = uicheckbox(app.BuildStacksUIFigure);
            app.C11.Text = '';
            app.D11 = uicheckbox(app.BuildStacksUIFigure);
            app.D11.Text = '';
            app.A12 = uicheckbox(app.BuildStacksUIFigure);
            app.A12.Text = '';
            app.B12 = uicheckbox(app.BuildStacksUIFigure);
            app.B12.Text = '';
            app.C12 = uicheckbox(app.BuildStacksUIFigure);
            app.C12.Text = '';
            app.D12 = uicheckbox(app.BuildStacksUIFigure);
            app.D12.Text = '';
            app.E07 = uicheckbox(app.BuildStacksUIFigure);
            app.E07.Text = '';
            app.F07 = uicheckbox(app.BuildStacksUIFigure);
            app.F07.Text = '';
            app.G07 = uicheckbox(app.BuildStacksUIFigure);
            app.G07.Text = '';
            app.H07 = uicheckbox(app.BuildStacksUIFigure);
            app.H07.Text = '';
            app.E08 = uicheckbox(app.BuildStacksUIFigure);
            app.E08.Text = '';
            app.F08 = uicheckbox(app.BuildStacksUIFigure);
            app.F08.Text = '';
            app.G08 = uicheckbox(app.BuildStacksUIFigure);
            app.G08.Text = '';
            app.H08 = uicheckbox(app.BuildStacksUIFigure);
            app.H08.Text = '';
            app.E09 = uicheckbox(app.BuildStacksUIFigure);
            app.E09.Text = '';
            app.F09 = uicheckbox(app.BuildStacksUIFigure);
            app.F09.Text = '';
            app.G09 = uicheckbox(app.BuildStacksUIFigure);
            app.G09.Text = '';
            app.H09 = uicheckbox(app.BuildStacksUIFigure);
            app.H09.Text = '';
            app.E10 = uicheckbox(app.BuildStacksUIFigure);
            app.E10.Text = '';
            app.F10 = uicheckbox(app.BuildStacksUIFigure);
            app.F10.Text = '';
            app.G10 = uicheckbox(app.BuildStacksUIFigure);
            app.G10.Text = '';
            app.H10 = uicheckbox(app.BuildStacksUIFigure);
            app.H10.Text = '';
            app.E11 = uicheckbox(app.BuildStacksUIFigure);
            app.E11.Text = '';
            app.F11 = uicheckbox(app.BuildStacksUIFigure);
            app.F11.Text = '';
            app.G11 = uicheckbox(app.BuildStacksUIFigure);
            app.G11.Text = '';
            app.H11 = uicheckbox(app.BuildStacksUIFigure);
            app.H11.Text = '';
            app.E12 = uicheckbox(app.BuildStacksUIFigure);
            app.E12.Text = '';
            app.F12 = uicheckbox(app.BuildStacksUIFigure);
            app.F12.Text = '';
            app.G12 = uicheckbox(app.BuildStacksUIFigure);
            app.G12.Text = '';
            app.H12 = uicheckbox(app.BuildStacksUIFigure);
            app.H12.Text = '';
            % Show the figure after all components are created
            app.BuildStacksUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = buildIXNStack

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BuildStacksUIFigure)

            % Execute the startup function
            runStartupFcn(app, @MoveToCenter)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BuildStacksUIFigure)
        end
    end
end